classdef Graph_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        SweepNumberEditField  matlab.ui.control.NumericEditField
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_4              matlab.ui.control.UIAxes
        SweepNumberLabel      matlab.ui.control.Label
        UIAxes_5              matlab.ui.control.UIAxes
        UIAxes_6              matlab.ui.control.UIAxes
    end

    
    properties (Access = private) % Properties allows for the saving of variables, 
        % to call the saved variables just put app.(variable name)
        D; % Data
    end
    
    methods (Access = private) % This is the helper function - this allows for multiple functions
        % to be created and stored and can later be called in callbacks
        
        function LoadData(app)
            temp = load(uigetfile,'trials');
            app.D = temp.trials;
            x = (-.1:1/999:.30)';
            plot(app.UIAxes,x,app.D.ch1{1,1});
            plot(app.UIAxes_2,x,app.D.ch2{1,1});
            plot(app.UIAxes_3,x,app.D.ch3{1,1});
            plot(app.UIAxes_4,x,app.D.ch4{1,1});
            plot(app.UIAxes_5,x,app.D.ch5{1,1});
            plot(app.UIAxes_6,x,app.D.ch6{1,1});
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LoadData(app)
        end

        % Callback function
        function ArtefactButtonPushed(app, event)
%             artgraph1(app)
        end

        % Callback function
        function ClearButtonPushed(app, event)
%             deletegraph1(app)
        end

        % Callback function
        function ArtefactButton_2Pushed(app, event)
%             artgraph2(app)
        end

        % Callback function
        function ClearButton_2Pushed(app, event)
%             deletegraph2(app)
        end

        % Value changed function: SweepNumberEditField
        function SweepNumberEditFieldValueChanged(app, event)
                               
            for value = app.SweepNumberEditField.Value
                reset(app.UIAxes);
                reset(app.UIAxes_2);
                reset(app.UIAxes_3);
                reset(app.UIAxes_4);
                reset(app.UIAxes_5);
                reset(app.UIAxes_6);
                
                x = (-.1:1/999:.30)';
                
                % plotting Channels
                plot(app.UIAxes,x,app.D.ch1{value,1});
                plot(app.UIAxes_2,x,app.D.ch2{value,1});
                plot(app.UIAxes_3,x,app.D.ch3{value,1});
                plot(app.UIAxes_4,x,app.D.ch4{value,1});
                plot(app.UIAxes_5,x,app.D.ch5{value,1});
                plot(app.UIAxes_6,x,app.D.ch6{value,1});
                
                % plotting MEP Onset
                b1 = app.D.ch1_MEP_time(value,1);
                b2 = app.D.ch2_MEP_time(value,1);
                b3 = app.D.ch3_MEP_time(value,1);
                b4 = app.D.ch4_MEP_time(value,1);
                b5 = app.D.ch5_MEP_time(value,1);
                b6 = app.D.ch6_MEP_time(value,1);

                hold(app.UIAxes);
                hold(app.UIAxes_2);
                hold(app.UIAxes_3);
                hold(app.UIAxes_4);
                hold(app.UIAxes_5);
                hold(app.UIAxes_6);
                
                xline(app.UIAxes,b1,'green');
                xline(app.UIAxes_2,b2,'green');
                xline(app.UIAxes_3,b3,'green');
                xline(app.UIAxes_4,b4,'green');
                xline(app.UIAxes_5,b5,'green');
                xline(app.UIAxes_6,b6,'green');
                
                % plotting MEP Offset
                d1 = app.D.ch1_MEP_offset(value,1);
                d2 = app.D.ch2_MEP_offset(value,1);
                d3 = app.D.ch3_MEP_offset(value,1);
                d4 = app.D.ch4_MEP_offset(value,1);
                d5 = app.D.ch5_MEP_offset(value,1);
                d6 = app.D.ch6_MEP_offset(value,1);
        
                xline(app.UIAxes,d1,'red');
                xline(app.UIAxes_2,d2,'red');
                xline(app.UIAxes_3,d3,'red')
                xline(app.UIAxes_4,d4,'red');
                xline(app.UIAxes_5,d5,'red');
                xline(app.UIAxes_6,d6,'red');

                % Plotting Artefact ------------ [ARTLOC -column 8] This needs to be changed
                % eventually
                
                xline(app.UIAxes,app.D.artloc(value,1),'--b');
                xline(app.UIAxes_2,app.D.artloc(value,1),'--b');
                xline(app.UIAxes_3,app.D.artloc(value,1),'--b');
                xline(app.UIAxes_4,app.D.artloc(value,1),'--b');
                xline(app.UIAxes_5,app.D.artloc(value,1),'--b');
                xline(app.UIAxes_6,app.D.artloc(value,1),'--b');
                                
                % Legend
                legend(app.UIAxes,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
                legend(app.UIAxes_2,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
                legend(app.UIAxes_3,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
                legend(app.UIAxes_4,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
                legend(app.UIAxes_5,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
                legend(app.UIAxes_6,'Signal','MEP_Onset','MEP_Offset','Artefact','Location','bestoutside');
            
            end
        end

        % Callback function
        function ArtefactButton_3Pushed(app, event)
%             artgraph3(app)
        end

        % Callback function
        function ArtefactButton_4Pushed(app, event)
%             artgraph4(app)
        end

        % Callback function
        function ClearButton_3Pushed(app, event)
%             deletegraph3(app)
        end

        % Callback function
        function ClearButton_4Pushed(app, event)
%             deletegraph4(app)
        end

        % Callback function
        function ArtefactButton_5Pushed(app, event)
%             artgraph5(app)
        end

        % Callback function
        function ArtefactButton_6Pushed(app, event)
%             artgraph6(app)
        end

        % Callback function
        function ClearButton_5Pushed(app, event)
%             deletegraph5(app)
        end

        % Callback function
        function ClearButton_6Pushed(app, event)
%             deletegraph6(app)
        end

        % Callback function
        function MEP_OnsetCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function MEP_OffsetCheckBoxValueChanged(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Channel 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Channel 2')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.SweepNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.SweepNumberEditField.ValueChangedFcn = createCallbackFcn(app, @SweepNumberEditFieldValueChanged, true);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Channel 3')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Figure 4')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.SweepNumberLabel = uilabel(app.UIFigure);
            app.SweepNumberLabel.Text = 'Sweep Number';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Channel 5')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Channel 6')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Graph_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end